classdef CSN50530v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSN50530UIFigure           matlab.ui.Figure
        LoadFile1Button            matlab.ui.control.Button
        LoadFile2Button            matlab.ui.control.Button
        ProcessButton              matlab.ui.control.Button
        TimeScaleKnobLabel         matlab.ui.control.Label
        TimeScaleKnob              matlab.ui.control.DiscreteKnob
        EuroEditFieldLabel         matlab.ui.control.Label
        EuroEditField              matlab.ui.control.NumericEditField
        CECEditFieldLabel          matlab.ui.control.Label
        CECEditField               matlab.ui.control.NumericEditField
        UIAxes1                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        CPUtime110sAltimeterLabel  matlab.ui.control.Label
        CPUtime                    Aero.ui.control.Altimeter
        Lamp                       matlab.ui.control.Lamp
        UIAxes3                    matlab.ui.control.UIAxes
        LogTextAreaLabel           matlab.ui.control.Label
        LogTextArea                matlab.ui.control.TextArea
        xButton                    matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        EuroEditFieldLabel_2       matlab.ui.control.Label
        EuroEditFieldLabel_3       matlab.ui.control.Label
        PlotleveledeffButton       matlab.ui.control.Button
        PlotlevelsButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        data1 % struct to store raw imported data from file 1
        data2 % struct to store raw imported data from file 2
        data % struct to store interpolated data
        tim = timer; % Description
        select %logical data selector
        levels
    end
    
    methods (Access = private)                
        function incrementAltitude(app,~)
            app.CPUtime.Altitude = app.CPUtime.Altitude + 1;
        end
        
        function startEx(app)
            app.CPUtime.Altitude = 0;
            app.Lamp.Color = 'red';
            start(app.tim);
        end
        
        function stopEx(app, str)
            stop(app.tim);
            app.Lamp.Color = 'green';
            if nargin > 1
                app.LogTextArea.Value{end + 1} = str;
            end
            tm = [ 'Execution time = ' num2str(app.CPUtime.Altitude/100) 's' ];
            app.LogTextArea.Value{end + 1} = tm;
        end
        
        function msg(app, text)
            app.LogTextArea.Value{end+1} = text;
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tim.TimerFcn = @(~,~)app.incrementAltitude;
            app.tim.ExecutionMode = 'fixedRate';
            app.tim.Period = 0.1;
            app.LogTextArea.Value = 'Ready...';
            app.UITable.Data = [ [5; 10; 20; 25; 30; 50; 75; 100 ] [] ];
        end

        % Button pushed function: LoadFile1Button
        function LoadFile1ButtonPushed(app, event)
        app.Lamp.Color = 'yellow';
            app.UIAxes1.cla();
            [filename1, path1] = uigetfile('*.txt');
            app.UIAxes1.Title.String = filename1;
        app.startEx();
            app.data1 = importfile1( [ path1 filename1 ] );
            app.data1.time = datenum( app.data1.TIMESTAMP, 'dd.mm.yyyy HH:MM:SS.FFF' );
            app.data1.datetime = datetime( app.data1.time, 'ConvertFrom', 'datenum');
        
            hold(app.UIAxes1,'on');
            plot( app.UIAxes1, app.data1.datetime, app.data1.CH1MPPW );
            plot( app.UIAxes1, app.data1.datetime, app.data1.CH1RMSW );
            yyaxis( app.UIAxes1, "right");
            plot( app.UIAxes1, app.data1.datetime, app.data1.CH1MPP );
            legend(app.UIAxes1,'Location','southeast');
            legend(app.UIAxes1, 'MPPW', 'RMSW', 'MPP');
        app.stopEx();
        end

        % Button pushed function: LoadFile2Button
        function LoadFile2ButtonPushed(app, event)
        app.Lamp.Color = 'yellow';
            app.UIAxes2.cla();
            [filename2, path2] = uigetfile('*.csv');
            app.UIAxes2.Title.String= filename2;
        app.startEx(); 
            app.data2 = importfile2( [ path2 filename2 ] );
            app.data2.time = datenum( app.data2.tsnorm1G1Narrow,'yyyy-mm-dd HH:MM:SS.FFF');
            app.data2.datetime = datetime( app.data2.time, 'ConvertFrom', 'datenum');
        app.Lamp.Color = 'yellow';     
            hold(app.UIAxes2,'on');
            plot( app.UIAxes2, app.data2.datetime, app.data2.p1G2NarrowW );
            plot( app.UIAxes2, app.data2.datetime, app.data2.p_SUMG1NarrowW );
            legend(app.UIAxes2,'Location','south');
            legend(app.UIAxes2, 'p1G2NarrowW', 'p_SUMG1NarrowW');
        app.stopEx();
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
        app.Lamp.Color = 'yellow';
            granularity = 1/24/60/60/app.TimeScaleKnob.Value;
            app.msg( [ 'Granularity = ' num2str(granularity) ] ); %
            app.data.time = ( max( app.data1.time(1), app.data2.time(1) ) : granularity : min( app.data1.time(end), app.data2.time(end) ) )';
            app.data.datetime = datetime( app.data.time, 'ConvertFrom', 'datenum');
        app.startEx();   
            app.data.CH1RMSW = interp1( app.data1.time, app.data1.CH1RMSW, app.data.time );
            app.data.CH1MPPW = interp1( app.data1.time, app.data1.CH1MPPW, app.data.time );
            app.data.CH1MPP = interp1( app.data1.time, app.data1.CH1MPP, app.data.time );
            
            app.data.p1G2NarrowW = interp1( app.data2.time, app.data2.p1G2NarrowW, app.data.time );
            app.data.p_SUMG1NarrowW = interp1( app.data2.time, app.data2.p_SUMG1NarrowW, app.data.time );

                       
            %% Extract levels
            app.levels = app.UITable.Data(:,1);
            app.msg( [ 'Levels: ' num2str(app.levels') ] ); %
            app.UIAxes3.cla();
            h = histogram(app.UIAxes3, app.data.CH1MPPW, 'BinMethod', 'sqrt');  
%             h = histogram(app.UIAxes3, app.data.p1G2NarrowW, 'BinMethod', 'sqrt');            
            t = topkrows(h.Values', numel(app.levels));
            e = h.BinEdges( h.Values >= t(end) );
            
            % select data corresponding to expected levels
            app.select = false( numel(app.data.time), numel(app.levels) );
            for i = 1 : numel(app.levels)
                app.select(:,i) = ( app.data.CH1MPPW > e(i) ) & ( app.data.CH1MPPW < ( e(i) + h.BinWidth ) ) ;
            end
                        
            %% Calculate average at levels
            conversion_eff = app.data.p_SUMG1NarrowW ./ app.data.p1G2NarrowW;
            valid = ~( isnan(conversion_eff) | isinf(conversion_eff) );
            
            ceff = zeros( numel(app.levels), 1 );
            
            for i = 1 : numel(app.levels)
                ceff(i) = mean( conversion_eff( valid & app.select(:,i) ) );
            end
            
            app.UITable.Data(:,2) = abs(ceff.*100)';
            
            % European efficiency
            app.EuroEditField.Value = 0.03*ceff(1) + 0.06*ceff(2) + 0.13*ceff(3) + 0.1*ceff(5) + 0.48*ceff(6) + 0.2*ceff(8);
            app.EuroEditField.Value = app.EuroEditField.Value * 100;
            app.msg( [ 'EURO Efficiency = ' num2str( abs(app.EuroEditField.Value) ) '%' ]);
            
            % CEC efficiency
            app.CECEditField.Value = 0.04*ceff(2) + 0.05*ceff(3) + 0.12*ceff(5) + 0.21*ceff(6) + 0.53*ceff(7) + 0.05*ceff(8);
            app.CECEditField.Value = app.CECEditField.Value * 100;
            app.msg( [ 'CEC Efficiency = ' num2str( abs(app.CECEditField.Value) ) '%' ]);
        app.stopEx();
        end

        % Close request function: CSN50530UIFigure
        function CSN50530UIFigureCloseRequest(app, event)
            % clean up timer
            if exist('app.tim','var')
                stop(app.tim);
            end
            
            %delete figure
            clf;
            
            delete(app);
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            stop(app.tim);
            
        end

        % Button pushed function: PlotleveledeffButton
        function PlotleveledeffButtonPushed(app, event)
            figure;
            plot( app.UITable.Data(:,1), app.UITable.Data(:,2), '*-');
        end

        % Button pushed function: PlotlevelsButton
        function PlotlevelsButtonPushed(app, event)
            % plot to check selected in data1
            figure('Name','Measured Data 1','NumberTitle','off'); hold on;
            plot( app.data.datetime, app.data.CH1MPPW, '.');
            for i = 1 : numel(app.levels)
                plot( app.data.datetime( app.select(:,i) ), app.data.CH1MPPW( app.select(:,i) ), 'o');
            end
            legend( [ "original"; num2str(app.levels) ] );
            
            
            % plot to check selected in data2
            figure('Name','p1G2NarrowW','NumberTitle','off'); hold on;
            plot( app.data.datetime, app.data.p1G2NarrowW, '.');
            for i = 1 : numel(app.levels)
                plot( app.data.datetime( app.select(:,i) ), app.data.p1G2NarrowW( app.select(:,i) ), 'o');
            end
            legend( [ "original"; num2str(app.levels) ] );
            
            
            % plot to check selected in data2
            figure('Name','p_SUMG1NarrowW','NumberTitle','off'); hold on;
            plot( app.data.datetime, app.data.p_SUMG1NarrowW, '.');
            for i = 1 : numel(app.levels)
                plot( app.data.datetime( app.select(:,i) ), app.data.p_SUMG1NarrowW( app.select(:,i) ), 'o');
            end
            legend( [ "original"; num2str(app.levels) ] );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSN50530UIFigure = uifigure('Visible', 'off');
            app.CSN50530UIFigure.Name = 'CSN50530';
            app.CSN50530UIFigure.CloseRequestFcn = createCallbackFcn(app, @CSN50530UIFigureCloseRequest, true);
            app.LoadFile1Button = uibutton(app.CSN50530UIFigure, 'push');
            app.LoadFile1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFile1ButtonPushed, true);
            app.LoadFile1Button.Text = 'Load File 1';
            app.LoadFile2Button = uibutton(app.CSN50530UIFigure, 'push');
            app.LoadFile2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFile2ButtonPushed, true);
            app.LoadFile2Button.Text = 'Load File 2';
            app.ProcessButton = uibutton(app.CSN50530UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TimeScaleKnobLabel = uilabel(app.CSN50530UIFigure);
            app.TimeScaleKnobLabel.Enable = 'off';
            app.TimeScaleKnobLabel.Text = 'Time Scale';
            app.TimeScaleKnob = uiknob(app.CSN50530UIFigure, 'discrete');
            app.TimeScaleKnob.Items = {'1 s', '1/10 s', '1/100 s', '1 ms', ''};
            app.TimeScaleKnob.ItemsData = {'1', '10', '100', '1000'};
            app.TimeScaleKnob.Enable = 'off';
            app.TimeScaleKnob.Value = '1';
            app.EuroEditFieldLabel = uilabel(app.CSN50530UIFigure);
            app.EuroEditFieldLabel.Text = 'Euro';
            app.EuroEditField = uieditfield(app.CSN50530UIFigure, 'numeric');
            app.EuroEditField.ValueDisplayFormat = '%.6f';
            app.EuroEditField.Editable = 'off';
            app.CECEditFieldLabel = uilabel(app.CSN50530UIFigure);
            app.CECEditFieldLabel.Text = 'CEC';
            app.CECEditField = uieditfield(app.CSN50530UIFigure, 'numeric');
            app.CECEditField.ValueDisplayFormat = '%.6f';
            app.CECEditField.Editable = 'off';
            app.UIAxes1 = uiaxes(app.CSN50530UIFigure);
            title(app.UIAxes1, 'File 1 not loaded')
            xlabel(app.UIAxes1, 'time')
            ylabel(app.UIAxes1, 'power [W]')
            app.UIAxes2 = uiaxes(app.CSN50530UIFigure);
            title(app.UIAxes2, 'File 2 not loaded')
            xlabel(app.UIAxes2, 'time')
            ylabel(app.UIAxes2, 'power [W]')
            app.CPUtime110sAltimeterLabel = uilabel(app.CSN50530UIFigure);
            app.CPUtime110sAltimeterLabel.Text = 'CPU time [1/10s]';
            app.CPUtime = uiaeroaltimeter(app.CSN50530UIFigure);
            app.Lamp = uilamp(app.CSN50530UIFigure);
            app.UIAxes3 = uiaxes(app.CSN50530UIFigure);
            title(app.UIAxes3, 'Histogram')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.LogTextAreaLabel = uilabel(app.CSN50530UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.CSN50530UIFigure);
            app.xButton = uibutton(app.CSN50530UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.UITable = uitable(app.CSN50530UIFigure);
            app.UITable.ColumnName = {'Levels [%]'; 'Average Eff. [%]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false];
            app.EuroEditFieldLabel_2 = uilabel(app.CSN50530UIFigure);
            app.EuroEditFieldLabel_2.Text = '%';
            app.EuroEditFieldLabel_3 = uilabel(app.CSN50530UIFigure);
            app.EuroEditFieldLabel_3.Text = '%';
            app.PlotleveledeffButton = uibutton(app.CSN50530UIFigure, 'push');
            app.PlotleveledeffButton.ButtonPushedFcn = createCallbackFcn(app, @PlotleveledeffButtonPushed, true);
            app.PlotleveledeffButton.Text = 'Plot leveled eff.';
            app.PlotlevelsButton = uibutton(app.CSN50530UIFigure, 'push');
            app.PlotlevelsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlevelsButtonPushed, true);
            app.PlotlevelsButton.Text = 'Plot levels';
            % Show the figure after all components are created
            app.CSN50530UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSN50530v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CSN50530UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSN50530UIFigure)
        end
    end
end